classdef success < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab1                      matlab.ui.container.Tab
        plotButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        IntensityWLabel           matlab.ui.control.Label
        principleButton           matlab.ui.control.Button
        CCKnobLabel               matlab.ui.control.Label
        CCKnob                    matlab.ui.control.Knob
        lmpAutoUpdate_2           matlab.ui.control.Lamp
        swAutoUpdate_2            matlab.ui.control.Switch
        lblAutoUpdate_2           matlab.ui.control.Label
        UIAxes4                   matlab.ui.control.UIAxes
        simulinkButton            matlab.ui.control.Button
        SliderLabel               matlab.ui.control.Label
        Slider                    matlab.ui.control.Slider
        MdKnobLabel               matlab.ui.control.Label
        Dx_fKnob                  matlab.ui.control.Knob
        Dx_fKnobLabel             matlab.ui.control.Label
        L1KnobLabel               matlab.ui.control.Label
        L1Knob                    matlab.ui.control.Knob
        L2KnobLabel               matlab.ui.control.Label
        L2Knob                    matlab.ui.control.Knob
        L_gKnobLabel              matlab.ui.control.Label
        L_gKnob                   matlab.ui.control.Knob
        L3KnobLabel               matlab.ui.control.Label
        L3Knob                    matlab.ui.control.Knob
        L4KnobLabel               matlab.ui.control.Label
        L4Knob                    matlab.ui.control.Knob
        CyclesKnobLabel           matlab.ui.control.Label
        CyclesKnob                matlab.ui.control.Knob
        PeakPowerWEditFieldLabel  matlab.ui.control.Label
        PeakPowerWEditField       matlab.ui.control.NumericEditField
        FWHMpsEditFieldLabel      matlab.ui.control.Label
        FWHMpsEditField           matlab.ui.control.NumericEditField
        MdKnob                    matlab.ui.control.Knob
        UIAxes2                   matlab.ui.control.UIAxes
        Tab                       matlab.ui.container.Tab
        UIAxes3_2                 matlab.ui.control.UIAxes
        UIAxes3_1                 matlab.ui.control.UIAxes
        FFTTab                    matlab.ui.container.Tab
        axPulsePlot               matlab.ui.control.UIAxes
        btnPlot                   matlab.ui.control.Button
        swPlotType                matlab.ui.control.Switch
        lmpAutoUpdate             matlab.ui.control.Lamp
        swAutoUpdate              matlab.ui.control.Switch
        lblAutoUpdate             matlab.ui.control.Label
        dknDispersion             matlab.ui.control.DiscreteKnob
        lblDispersion             matlab.ui.control.Label
        dknModulation             matlab.ui.control.DiscreteKnob
        lblModulation             matlab.ui.control.Label
        cknHighPass               matlab.ui.control.Knob
        lblHighPass               matlab.ui.control.Label
        cknWindow                 matlab.ui.control.Knob
        lblWindow                 matlab.ui.control.Label
        cknLowPass                matlab.ui.control.Knob
        lblLowPass                matlab.ui.control.Label
        cknEdge                   matlab.ui.control.Knob
        lblEdge                   matlab.ui.control.Label
        ddType                    matlab.ui.control.DropDown
        lblType                   matlab.ui.control.Label
        numSignalLength           matlab.ui.control.NumericEditField
        lblSignalLength           matlab.ui.control.Label
        numFrequency              matlab.ui.control.NumericEditField
        lblFrequency              matlab.ui.control.Label
    end

properties (Access = private)
        autoUpdate
end
methods (Access = private)
function updatePlot(app)
%%%% Programing parameters %%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
Cycles = app.CyclesKnob.Value;      % Number of cycles
T = 80;            % Span in time domain unit: ps
N = 2^10;          % Number of sample points
t_temp = linspace(-T/2,T/2,N+1)';     % Time vector
t = t_temp(1:N);   % Time series
Dt = T/N;          % Time domain sampling period
omega = (2*pi/T.*(-N/2:1:(N/2-1)))';  % Circular frequency sequence unit：rad/ps
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
C_vacuum = 2.998e-4;%  unit: m/ps
Lambda0 = 1030e-9;         %  unit: m
lambda = 2*pi*C_vacuum./(omega+2*pi*C_vacuum/Lambda0);  
lmda=lambda*1e9-Lambda0*1e9;% unit m
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%  gain fiber  
B2_g = 0.018;    % ps^2/m 
Gm_g = 0.0031;   % 1/(W*m) 
L_g=app.L_gKnob.Value;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%% gain g = G0./(1+trapz(t,abs(at).^2)/Es_g) parameter %%%%%
G0 = 2;                % unit:1/m
w = fftshift(omega);
Es_g = 80;             % pJ 
Dx_g = 50e-9;          % m 
Dw_g = 2*pi*2.998e-4*Dx_g/Lambda0^2;  % unit:rad/ps,THz
%%%%%%%%% Filter parameter %%%%%%%%%%%%%%%%%%%%
Dx_f=app.Dx_fKnob.Value;
Dw_f = 2*pi*2.998e-4*Dx_f/Lambda0.^2; % unit:rad/ps,THz
Loss_f = 0.01;         % Loss
%%%%%%%%% single mode fiber %%%%%%%%%%%%%%%%%%% 
B2_sm1 = 0.018;        % Beta2_s1, ps^2/m，即23e-3
Gm_sm1 = 3.1e-3;       % 1/(W*m)
L1=app.L1Knob.Value;
L2=app.L2Knob.Value;
L3=app.L3Knob.Value;
L4=app.L4Knob.Value;
%%%%%%%%%%% saturation absorber parameter %%%%%%%%%%%%%%%
Md=app.MdKnob.Value;
A_ns = 0.4;       % nonsaturation loss
Ps = 20;          % saturation power 
%%%%%%%%%%%%%% output coupler %%%%%%%%%%%%%%%%%%%%%%%%%%%
cc=app.CCKnob.Value;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
global yw01 yw02 yw03 yw04 yw05 yw06 yt01 yt02 yt03 yt04 yt05 yt06 
peak=100;  
FWHM=2; 
at_in=sqrt(peak/339)*sech(t/(FWHM*5)); 
aw = ifft(at_in);
for i=1:1:Cycles
%%%%%%%%%%%% fiber 1 %%%%%%%%%%%%
   [z,awz]=ode45('fiber',[0 L1],aw,[],omega,t,B2_sm1,Gm_sm1,0,Es_g,Dw_g);
   aw=awz(end,1:N);  
   if i==Cycles
      evaw = awz;
      evz = z;
      moniw_01 = aw(1:length(t));         
      monit_01 = fft(aw(1:length(t)));
      yw01 = abs(fftshift(moniw_01)).^2;
      yt01 = abs(monit_01).^2;
   end     
%%%%%%%%%%%% gain fiber %%%%%%%%%%%%
   [z,awz]=ode45('fiber',[0 L_g],aw,[],omega,t,B2_g,Gm_g,G0,Es_g,Dw_g);
   aw = awz(end,1:N);
   if i==Cycles
      evaw =[evaw;awz];     % 此处是分号，矩阵行的追加，每一行表示一个状态
      evz =[evz;z+L1];     % 位置追加
      moniw_02 = aw(1:length(t));  
      monit_02 = fft(aw(1:length(t))); 
      yw02 = abs(fftshift(moniw_02)).^2;      
      yt02 = abs(monit_02).^2;
      moni_02_norm=(abs(monit_02(1:length(t)).^2)/max(abs(monit_02)).^2);
energyt=trapz(t',moni_02_norm);
moni_02_norm = yt02/max(yt02);
plot(app.UIAxes3_1,t,moni_02_norm,'b','linewidth',2)
plot(app.UIAxes3_2,lmda,abs(fftshift(moniw_02(1:length(t)))).^2,'k','linewidth',2);
plot(app.UIAxes3_2,lmda,yw02,'r','linewidth',2);
figure(2)
chirp = -diff(phase(monit_02))/(t(2)-t(1));
[ax,h1,h2]=plotyy(t,(abs(monit_02)).^2,t,[0 chirp]);
set(h1,'linestyle','-','color','b','linewidth',2);
set(h2,'linestyle','--','color','g','linewidth',2);
set(gca,'XLim',[-20,20]);
title('经增益后的脉冲以及频率啁啾')
ylabel(ax(2),'Frequency Chirp(THz)'),
xlabel('Time(ps)'), ylabel('Intensity(W)')
end
%%%%%%%%%%%% fiber 2 %%%%%%%%%%%%
   [z,awz]=ode45('fiber',[0 L2],aw,[],omega,t,B2_sm1,Gm_sm1,0,Es_g,Dw_g);
   aw = awz(end,1:N);
   if i==Cycles
      evaw =[evaw;awz];
      evz =[evz;z+L1+L_g]; 
      moniw_03 = aw(1:length(t));  
      monit_03 = fft(aw(1:length(t))); 
      yw03 = abs(fftshift(moniw_03)).^2;
      yt03 = abs(monit_03).^2;
   end
%%%%%%%%%%%% saturable absorbtion %%%%%%%%%%%%%
%%%%%%%%%%%%%%%% output coupler %%%%%%%%%%%%%%%
   att = cc*fft(aw);  
   output(1:N,i) = (1-cc)*fft(aw);  
   at = att.*absorber(att,Md,Ps,A_ns);    
   aw = ifft(at);     
   if i==Cycles
      evaw =[evaw;aw];     
      evz =[evz;L1+L_g+L2+0.005];  
      moniw_04 = aw(1:length(t));  
      monit_04 = fft(aw(1:length(t)));  
      yw04 = abs(fftshift(moniw_04)).^2;    
      yt04 = abs(monit_04).^2;
   end
%%%%%%%%%%%%% fiber 3 %%%%%%%%%%%%%%
   [z,awz]=ode45('fiber',[0 L3],aw,[],omega,t,B2_sm1,Gm_sm1,0,Es_g,Dw_g);
   aw = awz(end,1:N);
   if i==Cycles
      evaw =[evaw;awz];
      evz =[evz;z+L1+L_g+L2+0.005]; 
      moniw_05 = aw(1:length(t));  
      monit_05 = fft(aw(1:length(t))); 
      yw05 = abs(fftshift(moniw_05)).^2;
      yt05 = abs(monit_05).^2;
   end
%%%%%%%%%%%%%%%% filter %%%%%%%%%%%%%%%%%%%%
   aw = aw.*filter1(omega,Dw_f,Loss_f);
   if i==Cycles
      evaw =[evaw;aw];
      evz =[evz;L1+L_g+L2+L3+0.01];  % mark
      moniw_06 = aw(1:length(t));  
      monit_06 = fft(aw(1:length(t))); 
      yw06 = abs(fftshift(moniw_06)).^2;
      yt06 = abs(monit_06).^2;
   end
 %%%%%%%%%%%%%%%%% fiber 4 %%%%%%%%%%%%%%%%%
   [z,awz]=ode45('fiber',[0 L4],aw,[],omega,t,B2_sm1,Gm_sm1,0,Es_g,Dw_g);
   aw = awz(end,1:N);
   if i==Cycles
      evaw =[evaw;awz];
      evz =[evz;z+L1+L_g+L2+L3+0.01]; 
      moniw_07 = aw(1:length(t));  
      monit_07 = fft(aw(1:length(t))); 
   end
 %%%%%%%%%%%%% output parameter %%%%%%%%%%%%%%
end
%%%%%%%%%%%%%%%%%%%% simulation %%%%%%%%%%%%%%
dis=1:1:app.CyclesKnob.Value;
waterfall(app.UIAxes,t,dis,(abs(output(:,dis))').^2)
contourf(app.UIAxes2,t,dis,(abs(output(:,dis))').^2)
for dis1=1:1:app.Slider.Value
plot(app.UIAxes4,t,(abs(output(:,dis1))').^2,'LineWidth',2)
pause(0.05)
end
m1=(abs(output(:,app.CyclesKnob.Value))').^2;
m2=max(m1);
m3=m2./2;
m4=find(abs(m1-m3)<0.2);
m5=m1(m4);
X=abs(m5-m3);
m6=find(X==min(X));
if m6<=size(m5,2)/2
m7=(512-m4(m6))*2*Dt;
else if m6>size(m5,2)/2
        m7=(m4(m6)-513)*2*Dt;
end
end
app.PeakPowerWEditField.Value=m2;
app.FWHMpsEditField.Value=m7;  
%%%%%%%%%%%%%%%%不同位置处的光谱和脉冲形状%%%%%%%%%%%%%%%%%%%
figure(3)
plot(lmda,yw01,'-',lmda,yw02,'--',lmda,yw03,':',lmda,yw04,'-.',lmda,yw05,lmda,yw06,'LineWidth',2);
set(gca,'XLim',[-40,40],'FontSize',14,'FontName','Times New Roman','FontWeight','bold')
xlabel('Wavelength(nm)'), ylabel('Intensity(W)')
h1=legend('YDF before','YDF after','SA before','SA after','Filter before','Filter after'); % ,'markersize',0
set(h1,'Box','off');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
figure(4) 
plot(t,yt01,t,yt02,'--',t,yt03,':',t,yt04,'-.',t,yt05,t,yt06,'LineWidth',2);
set(gca,'XLim',[-40,40],'FontSize',14,'FontName','Times New Roman','FontWeight','bold')
xlabel('time(ps)'), ylabel('Intensity(W)')
h1=legend('YDF before','YDF after','SA before','SA after','Filter before','Filter after');
set(h1,'Box','off');
end

function updatePlot2d(app)
            signalLength = app.numSignalLength.Value ;
            frequency = app.numFrequency.Value ;
            plot2dType = app.swPlotType.Value ;
             
            p = generatePulse(app) ;
            t = -signalLength/2:1/frequency:signalLength/2;
            if strcmp(plot2dType,'Pulse')
                plot(app.axPulsePlot, t, p);
                xlabel(app.axPulsePlot,'Time(s)');
            else
                lp=length(p);%求采样点的个数，即signallength*frequency
                Y=fft(p);%做傅立叶变换
                sig=abs(Y(1:ceil(lp/2)));%一半采样点取整的频域绝对值
                f=linspace(0, frequency/2, ceil(lp/2));
                plot(app.axPulsePlot, f(sig>1e-4), sig(sig>1e-4));%semilogy(app.axPulsePlot, f(sig>1e-4), sig(sig>1e-4));
                xlabel(app.axPulsePlot,'Frequency(Hz)');
            end            
            
        end
        function result = generatePulse(app)
            
            frequency = app.numFrequency.Value ;
            signalLength = app.numSignalLength.Value ;
            edge = app.cknEdge.Value ;
            window = app.cknWindow.Value ;
            modulation = str2num(app.dknModulation.Value) ;
            type = app.ddType.Value ;
            lowpass = app.cknLowPass.Value ;
            highpass = app.cknHighPass.Value ;
            dispersion = str2num(app.dknDispersion.Value) ;
            
            startFrequency = 10 ;
            stopFrequency = 20 ;
            
            t = -signalLength/2:1/frequency:signalLength/2 ;
            sig = (signalLength/(8*edge))^2 ;
            
            switch app.ddType.Value
       
                case 'gaussian'
                    y = exp(-(t).^2/sig) ;
                case 'sinc'
                    x = 2*pi*edge*50.*t/(5*signalLength) ;
                    y = sin(x)./x ;
                    y(x==0) = 1 ;
                case 'square'
                    y = (t > -signalLength/edge/2) & (t < signalLength/edge/2) ;
                case 'triangle'
                    y = (t + signalLength/edge/2).*(t < 0) - (t - signalLength/edge/2).*(t >= 0);
                    y(y < 0) = 0 ;
                case 'monocycle'
                    if (sig == 0)
                        y = t ;
                    else
                        y = 2*t./sig.*exp(-(t).^2/sig) ;
                    end
                case 'exponential'
                    y = exp(-t*8*edge/signalLength) ;
                    y(t<0) = 0 ;
                case 'biexponential'
                    y = exp(-abs(t)*8*edge/signalLength) ;
                case 'mexican hat'
                    z = t./sqrt(0.75*sig);
                    y = sqrt(1/2*pi).*(1-z.^2).*exp(-z.^2/2) ;
                case 'raised cosine'
                    rb = 2*edge*50/(5*signalLength);
                    x = pi.*t.*rb ;
                    y = sin(x)./x ;
                    y(x==0) = 1 ;
                    y = y.*(cos(2*pi*rb.*t)./(1 - (4*rb.*t).^2));
                case 'double sinc'
                    x1 = 2*stopFrequency*pi.*t ;
                    x2 = 2*startFrequency*pi.*t ;
                    y1 = sin(x1)./x1 ;
                    y1(x1==0) = 1 ;
                    y2 = sin(x2)./x2 ;
                    y2(x2==0) = 1 ;
                    y = stopFrequency*y1 - startFrequency*y2;
                case 'sinc squared'
                    x = 2*pi*edge*16.*t/(5*signalLength) ;
                    y = sin(x)./x ;
                    y(x==0) = 1 ;
                    y = y.^2 ;
                case 'sweep'
                    theta = startFrequency.*(t + signalLength/2) + ...
                        ((stopFrequency - startFrequency)/(signalLength)).*(t+signalLength/2).^2;
                    y = real(exp(1j*(2*pi.*theta - pi/2)));
            end
            
            if (lowpass < 1) || (highpass > 0) || (dispersion ~= 0)
                c = length(y) ; %y的最大个数
            end
            
            s = fft(y);
            sA = abs(s);
            sP = angle(s);%复数的相位角
            if (lowpass < 1)
                cP = ceil(lowpass*c/2);
                if (cP == 0)
                    sA(:) = 0;
                else
                    sA(cP:end-cP+2) = 0;
                end
            end
            
            if (highpass > 0)
                cP = floor(highpass*c/2);
                if (cP ~= 0)
                    sA(1:cP) = 0;
                    sA(end-cP+2:end) = 0;
                end
            end
            
            if (dispersion ~= 0)
                pp = dispersion.*linspace(0,2*pi,c);
                sP = sP + pp;
            end
            s2 = sA.*cos(sP) + 1j*sA.*sin(sP);
            y = real(ifft(s2));
            
            if (window > 0)
                c = length(y);
                w = ones(size(y));
                p1 = floor(c*window/2);
                % Window is defined in three sections: taper, constant, taper
                w(1:p1+1) = (-cos((0:p1)/p1*pi)+1)/2;
                w(end-p1:end) = (cos((0:p1)/p1*pi)+1)/2;
                y=w.*y;
            end
            
            if modulation ~= 0
                y = y.*cos(pi*t*modulation);
            end
            result = y./max(abs(y)) ;            
            
        end
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.swAutoUpdate.Value = 'on';
            app.swAutoUpdate_2.Value='on';
            app.swPlotType.Value = 'Pulse' ;
            app.btnPlot.Visible = 'off' ;
            app.plotButton.Visible = 'off' ;
            app.autoUpdate = 1 ;
            a=imread('Schematic.jpg');
            imshow(a);    
            updatePlot(app)
            updatePlot2d(app)
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
        updatePlot(app) 
        end

        % Value changed function: MdKnob
        function MdKnobValueChanged(app, event)
if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: CCKnob
        function CCKnobValueChanged(app, event)
 if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: L3Knob
        function L3KnobValueChanged(app, event)
if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: L1Knob
        function L1KnobValueChanged(app, event)
if app.autoUpdate
                updatePlot(app)
end
        end

        % Value changed function: L2Knob
        function L2KnobValueChanged(app, event)
if app.autoUpdate
updatePlot(app)
end
        end

        % Value changed function: Dx_fKnob
        function Dx_fKnobValueChanged(app, event)
if app.autoUpdate
                updatePlot(app)
end
        end

        % Value changed function: L4Knob
        function L4KnobValueChanged(app, event)
if app.autoUpdate
                updatePlot(app)
end
        end

        % Value changed function: L_gKnob
        function L_gKnobValueChanged(app, event)
if app.autoUpdate
                updatePlot(app)
end
        end

        % Button pushed function: principleButton
        function principleButtonPushed(app, event)
a=imread('Schematic.jpg');
imshow(a);
        end

        % Value changed function: cknHighPass
        function cknHighPassValueChanged(app, event)
  if app.autoUpdate
                updatePlot2d(app)
            end
        end

        % Value changed function: cknWindow
        function cknWindowValueChanged(app, event)
              if app.autoUpdate
                updatePlot2d(app)
              end
        end

        % Value changed function: cknEdge
        function cknEdgeValueChanged(app, event)
   if app.autoUpdate
                updatePlot2d(app)
   end
        end

        % Value changed function: cknLowPass
        function cknLowPassValueChanged(app, event)
            if app.autoUpdate
                updatePlot2d(app)
            end
        end

        % Value changed function: dknModulation
        function dknModulationValueChanged(app, event)
              if app.autoUpdate
                updatePlot2d(app)
              end
        end

        % Value changed function: dknDispersion
        function dknDispersionValueChanged(app, event)
        if app.autoUpdate
                updatePlot2d(app)
        end
        end

        % Value changed function: swAutoUpdate
        function swAutoUpdateValueChanged(app, event)
              if strcmp(app.swAutoUpdate.Value, 'on')
                app.autoUpdate = 1 ;
                app.btnPlot.Visible = 'off' ;
                app.lmpAutoUpdate.Color = [0 1 0] ;
              else
                app.autoUpdate = 0 ;
                app.btnPlot.Visible = 'on' ;
                app.lmpAutoUpdate.Color = [0.5 0 0.5] ;  
            end        
        end

        % Button pushed function: btnPlot
        function btnPlotButtonPushed(app, event)
            updatePlot2d(app)  
        end

        % Value changed function: ddType
        function ddTypeValueChanged(app, event)
             if app.autoUpdate
                updatePlot2d(app)
            end     
        end

        % Value changed function: numSignalLength
        function numSignalLengthValueChanged(app, event)
             if app.autoUpdate
                updatePlot2d(app)
            end     
        end

        % Value changed function: numFrequency
        function numFrequencyValueChanged(app, event)
          if app.autoUpdate
                updatePlot2d(app)
            end     
        end

        % Value changed function: swPlotType
        function swPlotTypeValueChanged(app, event)
              if app.autoUpdate
                updatePlot2d(app)
              end
        end

        % Value changed function: swAutoUpdate_2
        function swAutoUpdate_2ValueChanged(app, event)
          if strcmp(app.swAutoUpdate_2.Value, 'on')
                app.autoUpdate= 1 ;
                app.plotButton.Visible = 'off' ;
                app.lmpAutoUpdate_2.Color = [0 1 0] ;
              else
                app.autoUpdate= 0 ;
                app.plotButton.Visible = 'on' ;
                app.lmpAutoUpdate_2.Color = [0.5 0 0.5] ;  
            end        
        end

        % Value changed function: CyclesKnob
        function CyclesKnobValueChanged(app, event)
               if app.autoUpdate
                updatePlot(app)
            end
        end

        % Button pushed function: simulinkButton
        function simulinkButtonPushed(app, event)
open_system('fiber1.slx');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
                if app.autoUpdate
                updatePlot(app)
                end
        end

        % Value changed function: PeakPowerWEditField
        function PeakPowerWEditFieldValueChanged(app, event)
             if app.autoUpdate
                updatePlot(app)
             end
        end

        % Value changed function: FWHMpsEditField
        function FWHMpsEditFieldValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end          
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = '脉冲传输方程';
            app.plotButton = uibutton(app.Tab1, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.UIAxes = uiaxes(app.Tab1);
            title(app.UIAxes, '脉冲传输方程')
            xlabel(app.UIAxes, 'Time(ps)')
            ylabel(app.UIAxes, 'Round Trips')
            app.IntensityWLabel = uilabel(app.Tab1);
            app.IntensityWLabel.Text = 'Intensity(W)';
            app.principleButton = uibutton(app.Tab1, 'push');
            app.principleButton.ButtonPushedFcn = createCallbackFcn(app, @principleButtonPushed, true);
            app.principleButton.Text = 'principle';
            app.CCKnobLabel = uilabel(app.Tab1);
            app.CCKnobLabel.Text = 'CC';
            app.CCKnob = uiknob(app.Tab1, 'continuous');
            app.CCKnob.Limits = [0 1];
            app.CCKnob.ValueChangedFcn = createCallbackFcn(app, @CCKnobValueChanged, true);
            app.CCKnob.Value = 0.8;
            app.lmpAutoUpdate_2 = uilamp(app.Tab1);
            app.swAutoUpdate_2 = uiswitch(app.Tab1, 'slider');
            app.swAutoUpdate_2.Items = {'off', 'on'};
            app.swAutoUpdate_2.ValueChangedFcn = createCallbackFcn(app, @swAutoUpdate_2ValueChanged, true);
            app.swAutoUpdate_2.Value = 'on';
            app.lblAutoUpdate_2 = uilabel(app.Tab1);
            app.lblAutoUpdate_2.Text = 'Auto Update';
            app.UIAxes4 = uiaxes(app.Tab1);
            title(app.UIAxes4, '脉冲时域谱')
            xlabel(app.UIAxes4, 'Time(ps)')
            ylabel(app.UIAxes4, 'Intensity(W)')
            app.UIAxes4.XTick = [-40 -30 -20 -10 0 10 20 30 40];
            app.UIAxes4.YTick = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6];
            app.UIAxes4.ZTick = [];
            app.simulinkButton = uibutton(app.Tab1, 'push');
            app.simulinkButton.ButtonPushedFcn = createCallbackFcn(app, @simulinkButtonPushed, true);
            app.simulinkButton.Text = 'simulink';
            app.SliderLabel = uilabel(app.Tab1);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Tab1);
            app.Slider.Limits = [0 200];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 100;
            app.MdKnobLabel = uilabel(app.Tab1);
            app.MdKnobLabel.Text = 'Md';
            app.Dx_fKnob = uiknob(app.Tab1, 'continuous');
            app.Dx_fKnob.Limits = [0 5e-08];
            app.Dx_fKnob.ValueChangedFcn = createCallbackFcn(app, @Dx_fKnobValueChanged, true);
            app.Dx_fKnob.Value = 2e-08;
            app.Dx_fKnobLabel = uilabel(app.Tab1);
            app.Dx_fKnobLabel.Text = 'Dx_f';
            app.L1KnobLabel = uilabel(app.Tab1);
            app.L1KnobLabel.Text = 'L1';
            app.L1Knob = uiknob(app.Tab1, 'continuous');
            app.L1Knob.Limits = [0 1];
            app.L1Knob.ValueChangedFcn = createCallbackFcn(app, @L1KnobValueChanged, true);
            app.L1Knob.Value = 0.5;
            app.L2KnobLabel = uilabel(app.Tab1);
            app.L2KnobLabel.Text = 'L2';
            app.L2Knob = uiknob(app.Tab1, 'continuous');
            app.L2Knob.Limits = [0 1];
            app.L2Knob.ValueChangedFcn = createCallbackFcn(app, @L2KnobValueChanged, true);
            app.L2Knob.Value = 0.5;
            app.L_gKnobLabel = uilabel(app.Tab1);
            app.L_gKnobLabel.Text = 'L_g';
            app.L_gKnob = uiknob(app.Tab1, 'continuous');
            app.L_gKnob.Limits = [0 4];
            app.L_gKnob.ValueChangedFcn = createCallbackFcn(app, @L_gKnobValueChanged, true);
            app.L_gKnob.Value = 4;
            app.L3KnobLabel = uilabel(app.Tab1);
            app.L3KnobLabel.Text = 'L3';
            app.L3Knob = uiknob(app.Tab1, 'continuous');
            app.L3Knob.Limits = [0 1];
            app.L3Knob.ValueChangedFcn = createCallbackFcn(app, @L3KnobValueChanged, true);
            app.L3Knob.Value = 1;
            app.L4KnobLabel = uilabel(app.Tab1);
            app.L4KnobLabel.Text = 'L4';
            app.L4Knob = uiknob(app.Tab1, 'continuous');
            app.L4Knob.Limits = [0 1];
            app.L4Knob.ValueChangedFcn = createCallbackFcn(app, @L4KnobValueChanged, true);
            app.L4Knob.Value = 0.5;
            app.CyclesKnobLabel = uilabel(app.Tab1);
            app.CyclesKnobLabel.Text = 'Cycles';
            app.CyclesKnob = uiknob(app.Tab1, 'continuous');
            app.CyclesKnob.Limits = [0 200];
            app.CyclesKnob.ValueChangedFcn = createCallbackFcn(app, @CyclesKnobValueChanged, true);
            app.CyclesKnob.Value = 100;
            app.PeakPowerWEditFieldLabel = uilabel(app.Tab1);
            app.PeakPowerWEditFieldLabel.Text = 'Peak Power(W)';
            app.PeakPowerWEditField = uieditfield(app.Tab1, 'numeric');
            app.PeakPowerWEditField.ValueChangedFcn = createCallbackFcn(app, @PeakPowerWEditFieldValueChanged, true);
            app.FWHMpsEditFieldLabel = uilabel(app.Tab1);
            app.FWHMpsEditFieldLabel.Text = 'FWHM(ps)';
            app.FWHMpsEditField = uieditfield(app.Tab1, 'numeric');
            app.FWHMpsEditField.ValueChangedFcn = createCallbackFcn(app, @FWHMpsEditFieldValueChanged, true);
            app.MdKnob = uiknob(app.Tab1, 'continuous');
            app.MdKnob.Limits = [0 0.5];
            app.MdKnob.MajorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5];
            app.MdKnob.ValueChangedFcn = createCallbackFcn(app, @MdKnobValueChanged, true);
            app.MdKnob.Value = 0.05;
            app.UIAxes2 = uiaxes(app.Tab1);
            title(app.UIAxes2, '脉冲等高图')
            xlabel(app.UIAxes2, 'Time(ps)')
            ylabel(app.UIAxes2, 'Round Trips')
            app.UIAxes2.ZTick = [0 0.5 1 1.5 2 2.5 3 3.5 4];
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '腔内传输方程';
            app.UIAxes3_2 = uiaxes(app.Tab);
            title(app.UIAxes3_2, '经增益光纤后脉冲的光谱')
            xlabel(app.UIAxes3_2, 'Spectrum(nm)')
            ylabel(app.UIAxes3_2, 'Intensity(W)')
            app.UIAxes3_1 = uiaxes(app.Tab);
            title(app.UIAxes3_1, '经增益光纤后的时域脉冲')
            xlabel(app.UIAxes3_1, 'Time(ps)')
            ylabel(app.UIAxes3_1, 'Intensity(W)')
            app.FFTTab = uitab(app.TabGroup);
            app.FFTTab.Title = '典型脉冲的FFT变换';
            app.axPulsePlot = uiaxes(app.FFTTab);
            xlabel(app.axPulsePlot, 'X')
            ylabel(app.axPulsePlot, 'Y')
            app.btnPlot = uibutton(app.FFTTab, 'push');
            app.btnPlot.ButtonPushedFcn = createCallbackFcn(app, @btnPlotButtonPushed, true);
            app.btnPlot.Text = 'Plot2d';
            app.swPlotType = uiswitch(app.FFTTab, 'slider');
            app.swPlotType.Items = {'Pulse', 'Pulse Spectrum'};
            app.swPlotType.ValueChangedFcn = createCallbackFcn(app, @swPlotTypeValueChanged, true);
            app.swPlotType.Value = 'Pulse Spectrum';
            app.lmpAutoUpdate = uilamp(app.FFTTab);
            app.swAutoUpdate = uiswitch(app.FFTTab, 'slider');
            app.swAutoUpdate.Items = {'off', 'on'};
            app.swAutoUpdate.ValueChangedFcn = createCallbackFcn(app, @swAutoUpdateValueChanged, true);
            app.swAutoUpdate.Value = 'on';
            app.lblAutoUpdate = uilabel(app.FFTTab);
            app.lblAutoUpdate.Text = 'Auto Update';
            app.dknDispersion = uiknob(app.FFTTab, 'discrete');
            app.dknDispersion.Items = {'-1.0', '-0.5', '0', '0.5', '1.0'};
            app.dknDispersion.ValueChangedFcn = createCallbackFcn(app, @dknDispersionValueChanged, true);
            app.dknDispersion.Value = '0';
            app.lblDispersion = uilabel(app.FFTTab);
            app.lblDispersion.Text = 'Dispersion';
            app.dknModulation = uiknob(app.FFTTab, 'discrete');
            app.dknModulation.Items = {'0', '50', '100', '150', '200', '250'};
            app.dknModulation.ValueChangedFcn = createCallbackFcn(app, @dknModulationValueChanged, true);
            app.dknModulation.Value = '0';
            app.lblModulation = uilabel(app.FFTTab);
            app.lblModulation.Text = 'Modulation';
            app.cknHighPass = uiknob(app.FFTTab, 'continuous');
            app.cknHighPass.Limits = [0 1];
            app.cknHighPass.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.cknHighPass.ValueChangedFcn = createCallbackFcn(app, @cknHighPassValueChanged, true);
            app.lblHighPass = uilabel(app.FFTTab);
            app.lblHighPass.Text = 'High Pass';
            app.cknWindow = uiknob(app.FFTTab, 'continuous');
            app.cknWindow.Limits = [0 1];
            app.cknWindow.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.cknWindow.ValueChangedFcn = createCallbackFcn(app, @cknWindowValueChanged, true);
            app.lblWindow = uilabel(app.FFTTab);
            app.lblWindow.Text = 'Window';
            app.cknLowPass = uiknob(app.FFTTab, 'continuous');
            app.cknLowPass.Limits = [0 1];
            app.cknLowPass.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.cknLowPass.ValueChangedFcn = createCallbackFcn(app, @cknLowPassValueChanged, true);
            app.cknLowPass.Value = 0.4;
            app.lblLowPass = uilabel(app.FFTTab);
            app.lblLowPass.Text = 'Low Pass';
            app.cknEdge = uiknob(app.FFTTab, 'continuous');
            app.cknEdge.Limits = [0 4];
            app.cknEdge.ValueChangedFcn = createCallbackFcn(app, @cknEdgeValueChanged, true);
            app.cknEdge.Value = 1;
            app.lblEdge = uilabel(app.FFTTab);
            app.lblEdge.Text = 'Edge';
            app.ddType = uidropdown(app.FFTTab);
            app.ddType.Items = {'gaussian', 'sinc', 'square', 'triangle', 'monocycle', 'exponential', 'biexponential', 'mexican hat', 'raised cosine', 'double sinc', 'sinc squared', 'sweep'};
            app.ddType.ValueChangedFcn = createCallbackFcn(app, @ddTypeValueChanged, true);
            app.ddType.Value = 'gaussian';
            app.lblType = uilabel(app.FFTTab);
            app.lblType.Text = 'Type';
            app.numSignalLength = uieditfield(app.FFTTab, 'numeric');
            app.numSignalLength.Limits = [1 Inf];
            app.numSignalLength.ValueChangedFcn = createCallbackFcn(app, @numSignalLengthValueChanged, true);
            app.numSignalLength.Value = 2;
            app.lblSignalLength = uilabel(app.FFTTab);
            app.lblSignalLength.Text = 'Signal Length (s)';
            app.numFrequency = uieditfield(app.FFTTab, 'numeric');
            app.numFrequency.ValueChangedFcn = createCallbackFcn(app, @numFrequencyValueChanged, true);
            app.numFrequency.Value = 500;
            app.lblFrequency = uilabel(app.FFTTab);
            app.lblFrequency.Text = 'Frequency';
        end
    end

    methods (Access = public)

        % Construct app
        function app = success

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end